classdef OptiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_2                 matlab.ui.container.Panel
        StatusLabel             matlab.ui.control.Label
        BestPositionLabel_2     matlab.ui.control.Label
        BestValueLabel          matlab.ui.control.Label
        BestPositionLabel       matlab.ui.control.Label
        BestvalueLabel          matlab.ui.control.Label
        OPTIMIZATIONPROBLEMSOLUTIONLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        EpsilonEditField        matlab.ui.control.EditField
        EpsilonLabel            matlab.ui.control.Label
        GammaEditField          matlab.ui.control.EditField
        GammaLabel              matlab.ui.control.Label
        BetaEditField           matlab.ui.control.EditField
        BetaLabel               matlab.ui.control.Label
        AlphaEditField          matlab.ui.control.NumericEditField
        AlphaLabel              matlab.ui.control.Label
        FormulaLabel            matlab.ui.control.Label
        MaxIterationsEditField  matlab.ui.control.NumericEditField
        MaxIterationsLabel      matlab.ui.control.Label
        AlgorithmDropDown       matlab.ui.control.DropDown
        AlgorithmDropDownLabel  matlab.ui.control.Label
        ProblemDropDown         matlab.ui.control.DropDown
        ProblemDropDownLabel    matlab.ui.control.Label
        X4EditFieldLabel        matlab.ui.control.Label
        X3EditFieldLabel        matlab.ui.control.Label
        StartX4EditField        matlab.ui.control.NumericEditField
        StartX3EditField        matlab.ui.control.NumericEditField
        IterationTable          matlab.ui.control.Table
        RangeLabel              matlab.ui.control.Label
        StartYEditField         matlab.ui.control.NumericEditField
        StartYLabel             matlab.ui.control.Label
        StartXEditField         matlab.ui.control.NumericEditField
        StartXLabel             matlab.ui.control.Label
        FindOptimumButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            % Uygulama açılır açılmaz formülü yaz
            app.FormulaLabel.Text = ['f(x) = 1·x' char(8321) char(178) ' + 2·x' char(8322) char(178)];
            % 1. Çizim alanı için X ve Y noktalarını oluştur (-10 ile 10 arası)
            x_range = linspace(-10, 10, 50);
            [X, Y] = meshgrid(x_range, x_range);
            
            % 2. Sizin Probleminiz: Sum Squares Function Hesaplaması
            % Formül: f(x) = 1*x1^2 + 2*x2^2
            Z = 1 .* X.^2 + 2 .* Y.^2;
            
            % 3. Grafiği Arayüzdeki "UIAxes" üzerine çiz (Mesh grafiği)
            % NOT: Eğer grafik kutusunun adını değiştirdiyseniz 'app.UIAxes' kısmını düzeltin.
            mesh(app.UIAxes, X, Y, Z);
            
            % 4. Başlık ve Eksen İsimleri
            title(app.UIAxes, 'Sum Squares Function Topology', 'Color', [0.902 0.902 0.902]);
            xlabel(app.UIAxes, 'X1');
            ylabel(app.UIAxes, 'X2');
            zlabel(app.UIAxes, 'Cost');
            
            % 5. Renk skalasını ekle
            cb = colorbar(app.UIAxes);
            cb.Color = [0.902 0.902 0.902];
            % Uygulama açılır açılmaz Görünürlük Ayarını Tetikle
            % Bu kod, sanki kullanıcı Dropdown'ı değiştirmiş gibi davranır
           AlgorithmDropDownValueChanged(app, []);
            % Başlangıçta varsayılan problem (Sum Squares) seçili olduğu için
            % onun ayarlarını (Range, Start Point vb.) yükle:
            ProblemDropDownValueChanged(app, []);
        end

        % Button pushed function: FindOptimumButton
        function FindOptimumButtonPushed(app, event)
% --- 1. Girdileri Al ---
            algo = app.AlgorithmDropDown.Value; 
            max_iter = app.MaxIterationsEditField.Value;
            current_problem = app.ProblemDropDown.Value;
            
            % Parametreler
            converter = @(val) str2double(string(val));
            alpha = converter(app.AlphaEditField.Value);
            beta_val = converter(app.BetaEditField.Value); 
            if isnan(beta_val), beta_val = 0.9; end
            gamma_val = converter(app.GammaEditField.Value); 
            if isnan(gamma_val), gamma_val = 0.999; end
            eps_val = converter(app.EpsilonEditField.Value);
            if isnan(eps_val), eps_val = 1e-8; end

            % --- 2. Başlangıç Noktası (MANUEL GİRİŞ) ---
            u1 = app.StartXEditField.Value;
            u2 = app.StartYEditField.Value;
            
            if strcmp(current_problem, 'Eren_PowerSum_27')
                % 4 Boyutlu Giriş
                u3 = app.StartX3EditField.Value; 
                u4 = app.StartX4EditField.Value; 
                x = [u1, u2, u3, u4]; 
            else
                % Diğerleri 2 boyutlu
                x = [u1, u2];
            end
            
            % Değişkenler
            v = zeros(size(x)); s = zeros(size(x)); m = zeros(size(x));
            
            % Geçmiş (Max iterasyon kadar yer açtık ama belki hepsini kullanmayacağız)
            history = zeros(max_iter, numel(x)); 
            cost_history = zeros(max_iter, 1);
            
            % Erken durdurma için tolerans (Hata bu değerin altına inerse durur)
            tolerance = 1e-6; % 0.000001
            
            % --- 3. Döngü ---
            last_iter = 0; % Kaçıncı adımda durduğumuzu tutmak için
            
            for t = 1:max_iter
                history(t, :) = x; % Kaydet
                last_iter = t;     % Son adımı güncelle
                
                grad = zeros(size(x));
                current_cost = 0;
                
                % Maliyet ve Türev Hesapla
                if strcmp(current_problem, 'Ensar_SumSquares_22')
                    [current_cost, grad] = Ensar_SumSquares_22(x);
                elseif strcmp(current_problem, 'Batuhan_PermFunction_18')
                    [current_cost, grad] = Batuhan_PermFunction_18(x);
                elseif strcmp(current_problem, 'Eren_PowerSum_27')
                    [current_cost, grad] = Eren_PowerSum_27(x);
                elseif strcmp(current_problem, 'Emre_Rosenbrock_32')
                    [current_cost, grad] = Emre_Rosenbrock_32(x);
                end
                
                cost_history(t) = current_cost;
                
                % --- ERKEN DURDURMA (BREAK) ---
                if current_cost < tolerance
                    % Optimuma ulaştık, daha fazla dönmeye gerek yok!
                    break; 
                end
                
                % Algoritma Güncellemesi
                switch algo
                    case 'GD'
                        x = x - alpha .* grad;
                    case 'GDM'
                        v = beta_val .* v + alpha .* grad;
                        x = x - v;
                    case 'RMSProp'
                        s = gamma_val .* s + (1 - gamma_val) .* (grad .^ 2);
                        x = x - (alpha ./ (sqrt(s) + eps_val)) .* grad;
                    case 'ADAM'
                        m = beta_val .* m + (1 - beta_val) .* grad;
                        v = gamma_val .* v + (1 - gamma_val) .* (grad .^ 2);
                        m_hat = m ./ (1 - beta_val^t);
                        v_hat = v ./ (1 - gamma_val^t);
                        x = x - (alpha ./ (sqrt(v_hat) + eps_val)) .* m_hat;
                end
            end
            
            % --- DİZİLERİ KIRPMA (TRIM) ---
            % Eğer 2000 yerine 300. adımda durduysak, geri kalan 1700 tane boş satırı siliyoruz.
            history = history(1:last_iter, :);
            cost_history = cost_history(1:last_iter);
            
            % --- 4. Sonuçlar ---
            final_cost = cost_history(end);
            app.BestvalueLabel.Text = num2str(final_cost);
            
            if numel(x) == 4
                 app.BestPositionLabel.Text = sprintf('[%.2f, %.2f, %.2f, %.2f]', x(1), x(2), x(3), x(4));
            else
                 app.BestPositionLabel.Text = sprintf('[%.4f, %.4f]', x(1), x(2));
            end
            
            % HATA DURUMU
            target_error_limit = 0.1; 
            if isnan(final_cost)
                app.StatusLabel.Text = 'Status: FAILED (NaN)';
                app.StatusLabel.FontColor = [1 0 0]; 
            elseif final_cost < target_error_limit
                app.StatusLabel.Text = 'Status: SUCCESS (Error < 10%)';
                app.StatusLabel.FontColor = [0 0.5 0]; 
            else
                app.StatusLabel.Text = 'Status: CLOSE (Try more iter)';
                app.StatusLabel.FontColor = [0.8 0.5 0]; 
            end
            
            % --- 5. İTERASYON TABLOSU ---
            iter_col = (1:last_iter)'; % Sadece gerçekleşen adımlar
            cost_col = cost_history;
            
            if strcmp(current_problem, 'Eren_PowerSum_27')
                x1_col = history(:, 1);
                x2_col = history(:, 2);
                x3_col = history(:, 3);
                x4_col = history(:, 4);
                app.IterationTable.ColumnName = {'Iter', 'X1', 'X2', 'X3', 'X4', 'Cost'};
                app.IterationTable.Data = table(iter_col, x1_col, x2_col, x3_col, x4_col, cost_col);
            else
                x1_col = history(:, 1);
                x2_col = history(:, 2);
                app.IterationTable.ColumnName = {'Iter', 'X1', 'X2', 'Cost'};
                app.IterationTable.Data = table(iter_col, x1_col, x2_col, cost_col);
            end
            
            % --- 6. Grafik ---
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            if strcmp(current_problem, 'Ensar_SumSquares_22')
                r = linspace(-10, 10, 50); [X,Y]=meshgrid(r,r);
                mesh(app.UIAxes, X, Y, 1.*X.^2 + 2.*Y.^2);
            elseif strcmp(current_problem, 'Batuhan_PermFunction_18')
                r = linspace(-2, 2, 60); [X,Y]=meshgrid(r,r);
                Z = (11.*X + 12.*Y - 17).^2 + (11.*X.^2 + 12.*Y.^2 - 14).^2;
                mesh(app.UIAxes, X, Y, Z);
            elseif strcmp(current_problem, 'Eren_PowerSum_27')
                title(app.UIAxes, 'Power Sum (d=4) - Grafik Yok, Tabloya Bakınız');
            elseif strcmp(current_problem, 'Emre_Rosenbrock_32')
                r = linspace(-2, 2, 60); [X,Y]=meshgrid(r,r);
                Z = 100 .* (Y - X.^2).^2 + (1 - X).^2;
                mesh(app.UIAxes, X, Y, Z);
            end
            cb = colorbar(app.UIAxes);
            cb.Color = [0.902 0.902 0.902];
            
            if ~isnan(final_cost) && ~strcmp(current_problem, 'Eren_PowerSum_27')
                plot3(app.UIAxes, history(:,1), history(:,2), cost_history, 'r.-', 'LineWidth', 1.5, 'MarkerSize', 10);
                plot3(app.UIAxes, x(1), x(2), cost_history(end), 'go', 'MarkerSize', 10, 'MarkerFaceColor', 'g');
            end
            hold(app.UIAxes, 'off');
        end

        % Value changed function: ProblemDropDown
        function ProblemDropDownValueChanged(app, event)
        selectedProblem = app.ProblemDropDown.Value;
            
            % 1. Önce Ekstra Kutuları GİZLE (Varsayılan)
            % Eğer label isimlerin farklıysa burayı kontrol et!
            app.X3EditFieldLabel.Visible = 'off'; 
            app.StartX3EditField.Visible = 'off';
            app.X4EditFieldLabel.Visible = 'off';
            app.StartX4EditField.Visible = 'off';
            
            % Eksenleri temizle
            cla(app.UIAxes);
            title(app.UIAxes, '');
            
            if strcmp(selectedProblem, 'Ensar_SumSquares_22')
                app.FormulaLabel.Text = ['f(x) = 1·x' char(8321) char(178) ' + 2·x' char(8322) char(178)];
                app.RangeLabel.Text = 'Range: [-10, 10]';
                app.StartXEditField.Value = -5;
                app.StartYEditField.Value = 5;
                
                x_range = linspace(-10, 10, 50); [X, Y] = meshgrid(x_range, x_range);
                Z = 1 .* X.^2 + 2 .* Y.^2;
                mesh(app.UIAxes, X, Y, Z);
                title(app.UIAxes, 'Sum Squares (Ensar-22)');
                app.AlphaEditField.Value = 0.1;
                app.MaxIterationsEditField.Value = 100;

            elseif strcmp(selectedProblem, 'Batuhan_PermFunction_18') 
                app.FormulaLabel.Text = 'f(x) = Perm Function (d=2, β=10)';
                app.RangeLabel.Text = 'Range: [-2, 2]';
                app.StartXEditField.Value = -2;
                app.StartYEditField.Value = 2;
                
                x_range = linspace(-2, 2, 60); [X, Y] = meshgrid(x_range, x_range);
                Z = (11.*X + 12.*Y - 17).^2 + (11.*X.^2 + 12.*Y.^2 - 14).^2;
                mesh(app.UIAxes, X, Y, Z);
                title(app.UIAxes, 'Perm Function (Batuhan-18)');
                app.AlphaEditField.Value = 0.00001; 
                app.MaxIterationsEditField.Value = 2000;

            elseif strcmp(selectedProblem, 'Eren_PowerSum_27')
                % --- ENSAR (4 BOYUTLU - MANUEL GİRİŞ) ---
                app.FormulaLabel.Text = 'f(x) = Power Sum (d=4, b=[8,18,44,114])';
                app.RangeLabel.Text = 'Range: - (4D)';
                
                % 2. Ekstra Kutuları GÖSTER (Senin verdiğin isimlerle)
                app.X3EditFieldLabel.Visible = 'on';
                app.StartX3EditField.Visible = 'on';
                app.X4EditFieldLabel.Visible = 'on';
                app.StartX4EditField.Visible = 'on';
                
                % Varsayılan Değerler (1, 2, 2, 3 hedeftir)
                % Kullanıcıya kolaylık olsun diye optimuma yakın başlatalım
                app.StartXEditField.Value = 1.5;      % x1
                app.StartYEditField.Value = 2.5;      % x2
                app.StartX3EditField.Value = 2.5;          % x3 (Manuel)
                app.StartX4EditField.Value = 3.5;          % x4 (Manuel)
                
                % Grafik Yok - Sadece Bilgi
                title(app.UIAxes, 'Power Sum (Eren-27)');
                
                app.AlphaEditField.Value = 0.0001;
                app.MaxIterationsEditField.Value = 2000;

            elseif strcmp(selectedProblem, 'Emre_Rosenbrock_32')
                app.FormulaLabel.Text = 'f(x) = 100(x₂ - x₁²)² + (1 - x₁)²';
                app.RangeLabel.Text = 'Range: [-5, 10]';
                app.StartXEditField.Value = -2.048;
                app.StartYEditField.Value = 2.048;
                
                x_range = linspace(-2, 2, 60); [X, Y] = meshgrid(x_range, x_range);
                Z = 100 .* (Y - X.^2).^2 + (1 - X).^2;
                mesh(app.UIAxes, X, Y, Z);
                title(app.UIAxes, 'Rosenbrock (Emre-32)');
                app.AlphaEditField.Value = 0.001; 
                app.MaxIterationsEditField.Value = 3000;
            end
            cb = colorbar(app.UIAxes);
            cb.Color = [0.902 0.902 0.902];
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
           % 1. Seçilen Algoritmayı Al
            algo = app.AlgorithmDropDown.Value;
            
            % 2. Önce Tüm Parametre Kutularını GİZLE (Varsayılan: Kapalı)
            app.BetaEditField.Visible = 'off';
            app.GammaEditField.Visible = 'off';
            app.EpsilonEditField.Visible = 'off';
            
            % 3. Etiketleri Varsayılan Haline Getir (Sıfırlama)
            % Bunu yapmazsak ADAM'dan sonra diğerlerine geçince isimler yanlış kalır.
            app.BetaLabel.Text = 'Beta:';
            app.GammaLabel.Text = 'Gamma:';
            
            % Learning Rate kutusu her zaman açık kalsın
            app.AlphaEditField.Visible = 'on';
            % 3. Seçime Göre Gerekli Kutuları AÇ
            switch algo
                case 'GD'
                    % Sadece Alpha (Learning Rate) gereklidir, o da zaten açık.
                    
                case 'GDM'
                    % GDM için Beta gereklidir
                    app.BetaEditField.Visible = 'on';
                    app.AlphaEditField.Value = 0.00001; 
                        app.BetaEditField.Value = '0.6'; % Frenli momentum
                        app.MaxIterationsEditField.Value = 3000;
                        
                        % Başlangıç: Hedefe yakın
                        app.StartXEditField.Value = 0.9;
                        app.StartYEditField.Value = 1.9;
                        app.StartX3EditField.Value = 2.1;
                        app.StartX4EditField.Value = 3.1;

                    
                case 'RMSProp'
                    % RMSProp için Gamma ve Epsilon gereklidir
                    app.GammaEditField.Visible = 'on';
                    app.EpsilonEditField.Visible = 'on';
                    app.AlphaEditField.Value = 0.001; 
                        app.MaxIterationsEditField.Value = 2000;
                        
                        % Başlangıç: Biraz uzak olabilir (Kirli Başlangıç)
                        app.StartXEditField.Value = 0.5;
                        app.StartYEditField.Value = 2.5;
                        app.StartX3EditField.Value = 1.5;
                        app.StartX4EditField.Value = 3.5;
                    
                case 'ADAM'
                    % ADAM için Hepsi (Beta, Gamma, Epsilon) gereklidir
                    app.BetaEditField.Visible = 'on';
                    app.GammaEditField.Visible = 'on';
                    app.EpsilonEditField.Visible = 'on';
                    
                    % --- KRİTİK DÜZELTME BURADA ---
                    % Kullanıcı arayüzünde doğru isimlerin görünmesi için:
                    app.BetaLabel.Text = 'Beta 1:';  % Eski Beta
                    app.GammaLabel.Text = 'Beta 2:'; % Eski Gamma
                    % ------------------------------

                    app.AlphaEditField.Value = 0.01; 
                    app.BetaEditField.Value = '0.9';   % Beta 1 varsayılan
                    app.GammaEditField.Value = '0.999'; % Beta 2 varsayılan
                        app.MaxIterationsEditField.Value = 2000;
                        
                        % Başlangıç: Uzaktan bile gelse çözer
                        app.StartXEditField.Value = 0.5;
                        app.StartYEditField.Value = 2.5;
                        app.StartX3EditField.Value = 1.5;
                        app.StartX4EditField.Value = 3.5;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FindOptimumButton = uibutton(app.UIFigure, 'push');
            app.FindOptimumButton.ButtonPushedFcn = createCallbackFcn(app, @FindOptimumButtonPushed, true);
            app.FindOptimumButton.Text = 'Find Optimum';
            app.StartXLabel = uilabel(app.UIFigure);
            app.StartXLabel.Text = 'Start X';
            app.StartXEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartYLabel = uilabel(app.UIFigure);
            app.StartYLabel.Text = 'Start Y';
            app.StartYEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeLabel = uilabel(app.UIFigure);
            app.RangeLabel.Text = 'Range';
            app.IterationTable = uitable(app.UIFigure);
            app.IterationTable.ColumnName = {'Iter'; 'X1'; 'X2'; 'Cost'};
            app.IterationTable.RowName = {};
            app.StartX3EditField = uieditfield(app.UIFigure, 'numeric');
            app.StartX4EditField = uieditfield(app.UIFigure, 'numeric');
            app.X3EditFieldLabel = uilabel(app.UIFigure);
            app.X3EditFieldLabel.Text = 'X3';
            app.X4EditFieldLabel = uilabel(app.UIFigure);
            app.X4EditFieldLabel.Text = 'X4';
            app.Panel = uipanel(app.UIFigure);
            app.ProblemDropDownLabel = uilabel(app.Panel);
            app.ProblemDropDownLabel.Text = 'Problem';
            app.ProblemDropDown = uidropdown(app.Panel);
            app.ProblemDropDown.Items = {'Ensar_SumSquares_22', 'Batuhan_PermFunction_18', 'Eren_PowerSum_27', 'Emre_Rosenbrock_32'};
            app.ProblemDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemDropDownValueChanged, true);
            app.ProblemDropDown.Value = 'Ensar_SumSquares_22';
            app.AlgorithmDropDownLabel = uilabel(app.Panel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.Panel);
            app.AlgorithmDropDown.Items = {'GD', 'GDM', 'RMSProp', 'ADAM'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'GD';
            app.MaxIterationsLabel = uilabel(app.Panel);
            app.MaxIterationsLabel.Text = 'Max Iterations:';
            app.MaxIterationsEditField = uieditfield(app.Panel, 'numeric');
            app.MaxIterationsEditField.Value = 100;
            app.FormulaLabel = uilabel(app.Panel);
            app.FormulaLabel.Text = '';
            app.AlphaLabel = uilabel(app.Panel);
            app.AlphaLabel.Text = 'Alpha:';
            app.AlphaEditField = uieditfield(app.Panel, 'numeric');
            app.AlphaEditField.ValueDisplayFormat = '%.8g';
            app.BetaLabel = uilabel(app.Panel);
            app.BetaLabel.Text = 'Beta:';
            app.BetaEditField = uieditfield(app.Panel, 'text');
            app.BetaEditField.Value = '0.9';
            app.GammaLabel = uilabel(app.Panel);
            app.GammaLabel.Text = 'Gamma:';
            app.GammaEditField = uieditfield(app.Panel, 'text');
            app.GammaEditField.Value = '0.999';
            app.EpsilonLabel = uilabel(app.Panel);
            app.EpsilonLabel.Text = 'Epsilon:';
            app.EpsilonEditField = uieditfield(app.Panel, 'text');
            app.EpsilonEditField.Value = '0.00000001';
            app.OPTIMIZATIONPROBLEMSOLUTIONLabel = uilabel(app.UIFigure);
            app.OPTIMIZATIONPROBLEMSOLUTIONLabel.Text = 'OPTIMIZATION PROBLEM SOLUTION';
            app.Panel_2 = uipanel(app.UIFigure);
            app.BestvalueLabel = uilabel(app.Panel_2);
            app.BestvalueLabel.Text = 'Best value';
            app.BestPositionLabel = uilabel(app.Panel_2);
            app.BestPositionLabel.Text = 'Best Position';
            app.BestValueLabel = uilabel(app.Panel_2);
            app.BestValueLabel.Text = 'Best Value';
            app.BestPositionLabel_2 = uilabel(app.Panel_2);
            app.BestPositionLabel_2.Text = 'Best Position';
            app.StatusLabel = uilabel(app.Panel_2);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptiApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end